classdef app_detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ArquivoSelecionadoTab         matlab.ui.container.Tab
        BuscarArquivoEDF              matlab.ui.control.Button
        ArquivoEDFEditFieldLabel      matlab.ui.control.Label
        ArquivoEDFEditField           matlab.ui.control.EditField
        ArquivoTSEEditFieldLabel      matlab.ui.control.Label
        ArquivoTSEEditField           matlab.ui.control.EditField
        ConfiguraoclassificadorTab    matlab.ui.container.Tab
        SelecionarclassificadorDropDownLabel  matlab.ui.control.Label
        SelecionarclassificadorDropDown  matlab.ui.control.DropDown
        TreinarRede                   matlab.ui.control.Button
        ClassificarDados              matlab.ui.control.Button
        ResetarEstado                 matlab.ui.control.Button
        TreinandoLabel                matlab.ui.control.Label
        UltimotreinamentofeitoLabel   matlab.ui.control.Label
        ddmmyyyyhhmmssLabel           matlab.ui.control.Label
        DetectorconvulsoLabel         matlab.ui.control.Label
        EstatsticasClassificaoPanel   matlab.ui.container.Panel
        VerdadeirosPositivosEditFieldLabel  matlab.ui.control.Label
        VerdadeirosPositivos          matlab.ui.control.EditField
        VerdadeirosNegativosEditFieldLabel  matlab.ui.control.Label
        VerdadeirosNegativos          matlab.ui.control.EditField
        FalsoPositivosEditFieldLabel  matlab.ui.control.Label
        FalsoPositivos                matlab.ui.control.EditField
        FalsoNegativosEditFieldLabel  matlab.ui.control.Label
        FalsoNegativos                matlab.ui.control.EditField
        FmeasureEditFieldLabel        matlab.ui.control.Label
        Fmeasure                      matlab.ui.control.EditField
        GmeanEditFieldLabel           matlab.ui.control.Label
        Gmean                         matlab.ui.control.EditField
        AcuraciaTotalLabel            matlab.ui.control.Label
        AcurciaTotal                  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        caminho_arquivos = ''; % Caminho que se encontra o arquivo EDF e TSE
        
        nome_arquivo_edf = ''; % Arquivo EDF carregado atual
        
        nome_arquivo_tse = ''; % Arquivo TSE carregado atual
        
        caminho_arquivo_edf = ''; % Caminho do arquivo EDF
        
        caminho_arquivo_tse = ''; % Caminho do arquivo TSE
        
        sinal_carregado_edf % Matriz carregada pelo arquivo edf
        
        header_sinal_edf % Cabecalho do arquivo EDF
        
        eventos_sinal % Eventos do sinal carregado
        
        sinal_eeg % Instancia do Sinal, contendo todas as informações
        
        classificador_selecionado
        
        rede_neural_simples
        
        rede_convolucional
        
        rede_foi_treinada = false;
        
        camadas_rede_convolucional
        
        oldpath = addpath(genpath('MATLAB\')); % Description
    end
    
    methods (Access = private)
        
        function EDFCarregadoCorretamente(app)
            app.ArquivoEDFEditField.Value = app.caminho_arquivo_edf;
            app.ArquivoTSEEditField.Value = app.caminho_arquivo_tse;
            
            app.ClassificarDados.Enable = 'on';
            app.TreinarRede.Enable = 'on';
        end
        
        function CarregarRedeConvolucional(app)
            rede = alexnet;
            app.camadas_rede_convolucional = rede.Layers(1:end-3);
            app.camadas_rede_convolucional = [
                app.camadas_rede_convolucional
                fullyConnectedLayer(2)
                softmaxLayer
                classificationLayer
            ];
        end
        
        function CarregarRedeNeural(app)
            % opcoes: 'trainbr', 'trainscg'
            funcao_otimizacao = 'trainbr';
            numero_neuronios = 25;
            app.rede_neural_simples = patternnet(numero_neuronios, funcao_otimizacao);
            
            app.rede_neural_simples.divideParam.trainRatio = 75/100;
            app.rede_neural_simples.divideParam.valRatio = 15/100;
            app.rede_neural_simples.divideParam.testRatio = 10/100;
        end
    
        function [imagens_entrada_redimensionadas, saida_real] = ProcessamentoDadosDeeplearning(app)
            app.AmostragemFrequenciasDeInteresse();
            imagens_entrada = app.CriarImagens();
            imagens_entrada = [ imagens_entrada{:} ];
            saida_real = double([ imagens_entrada(:).ocorre_convulsao ]);
            
            imagens_entrada_normalizadas = app.NormalizarImagens(imagens_entrada);
            
            imagens_entrada_redimensionadas = app.RedimensionarImagens(imagens_entrada_normalizadas);
        end
        
        function TreinarRedeConvolucional(app)
            [imagens_entrada, saida_real] = app.ProcessamentoDadosDeeplearning();
            
            [imagens_treinamento, imagens_validacao, ...
             imagens_teste, saida_treinamento, ...
             saida_validacao, saida_teste] = app.SeparacaoDadosTreinamentoValidacaoTesteDeepLearning(imagens_entrada, saida_real);
        
            miniBatchSize = 10;
            options = trainingOptions('sgdm', ...
                'MiniBatchSize',miniBatchSize, ...
                'MaxEpochs',5, ...
                'InitialLearnRate',3e-4, ...
                'Shuffle','every-epoch', ...
                'Verbose',false, ...
                'ValidationData',{imagens_validacao, categorical(saida_validacao)},...
                'Plots','training-progress');
            
            app.rede_convolucional = trainNetwork(imagens_treinamento, categorical(saida_treinamento), ...
                app.camadas_rede_convolucional, options);
        end
    
        function [saida_real, saida_resultante_da_rede] = ClassificarRedeConvolucional(app)
            if ~isempty(app.rede_convolucional)
                [imagens_entrada, saida_real] = app.ProcessamentoDadosDeeplearning();
                
                saida_resultante_da_rede = predict(app.rede_convolucional, imagens_entrada);
                saida_resultante_da_rede = transpose(double(saida_resultante_da_rede(:,1) < saida_resultante_da_rede(:,2)));
            
                app.CalcularEstatisticasClassificacao(saida_real, saida_resultante_da_rede);
            end
        end
        
        function AmostragemFrequenciasDeInteresse(app)
            delta_theta = [1 7];
            alpha_beta = [8 30];
            gama = [31 100];
            
            % Decompoe-se em 3 faixas de frequencia os 21 canais do EEG
            sinal_delta_theta = app.sinal_eeg.DecomporSinalEmFaixaDeFrequencia(delta_theta);
            sinal_alpha_beta = app.sinal_eeg.DecomporSinalEmFaixaDeFrequencia(alpha_beta);
            sinal_gama = app.sinal_eeg.DecomporSinalEmFaixaDeFrequencia(gama);
            
            % Atribui os sinais decompostos no objeto 'sinal_associado'
            app.sinal_eeg.AtribuirSinaisDasFrequenciasDeInteresse(sinal_delta_theta, sinal_alpha_beta, sinal_gama);
        end
        
        function imagens_entrada = CriarImagens(app)
            sinal_delta_theta = app.DicionarioParaMatriz(app.sinal_eeg.canais_delta_theta);
            sinal_alpha_beta = app.DicionarioParaMatriz(app.sinal_eeg.canais_alpha_beta);
            sinal_gama = app.DicionarioParaMatriz(app.sinal_eeg.canais_gama);
            
            % Divide-se o sinal em faixas de intervalos
            tamanho_corte = 3; % segundos
            sinais_divididos_delta_theta = DividirSinal(sinal_delta_theta, tamanho_corte, app.sinal_eeg.frequencia_de_amostragem);
            sinais_divididos_alpha_beta = DividirSinal(sinal_alpha_beta, tamanho_corte, app.sinal_eeg.frequencia_de_amostragem);
            sinais_divididos_gama = DividirSinal(sinal_gama, tamanho_corte, app.sinal_eeg.frequencia_de_amostragem);
            
            imagens_entrada = CriarImagensEntrada(sinais_divididos_delta_theta, sinais_divididos_alpha_beta, sinais_divididos_gama);
            imagens_entrada = AssociarTrechosDeSinalComTipoDeEvento(imagens_entrada, app.eventos_sinal);
        end
    
        function imagens_normalizadas = NormalizarImagens(app, imagens_entrada)
            tamanho_imagem = [ size(imagens_entrada(1).imagem_delta_theta) 3 size(imagens_entrada, 2) ];
            
            imagens_normalizadas = zeros(tamanho_imagem);
            
            for i = 1:size(imagens_entrada, 2)
                imagens_normalizadas(:, 1:size(imagens_entrada(i).imagem_delta_theta, 2), 1, i) = imagens_entrada(i).imagem_delta_theta;
                imagens_normalizadas(:, 1:size(imagens_entrada(i).imagem_alpha_beta, 2), 2, i) = imagens_entrada(i).imagem_alpha_beta;
                imagens_normalizadas(:, 1:size(imagens_entrada(i).imagem_gama, 2), 3, i) = imagens_entrada(i).imagem_gama;
            end
        end
    
        function imagens_redimensionadas = RedimensionarImagens(app, imagens)
            tamanho_entrada = app.camadas_rede_convolucional(1).InputSize;
            imagens_redimensionadas = imresize(imagens, [21 tamanho_entrada(1)]);
            
            tamanho_imagens_redimensionadas = size(imagens_redimensionadas);
            tamanho_maximo_imagem = max(tamanho_imagens_redimensionadas(1:2));
            
            quantidade_padding_a_adicionar = tamanho_maximo_imagem - tamanho_imagens_redimensionadas(1:2);
            
            imagens_redimensionadas = padarray(imagens_redimensionadas, quantidade_padding_a_adicionar, 0, 'post');
        end
    
        function [imagens_treinamento, imagens_validacao, imagens_teste, saida_treinamento, saida_validacao, saida_teste] = SeparacaoDadosTreinamentoValidacaoTesteDeepLearning(app, imagens_entrada, saida_real)
            quantidade_dados = size(imagens_entrada, 4);
            indice_treino = ceil(quantidade_dados*0.7);
            indice_validacao = ceil(quantidade_dados*0.85);
            
            indices_aleatorios = randperm(quantidade_dados);
            
            imagens_entrada_dividido_aleatoriamente = imagens_entrada(:, :, :, indices_aleatorios);
            saida_real = saida_real(indices_aleatorios);
            
            imagens_treinamento = imagens_entrada_dividido_aleatoriamente(:, :, :, 1:indice_treino);
            saida_treinamento = saida_real(1:indice_treino);
            
            imagens_validacao = imagens_entrada_dividido_aleatoriamente(:, :, :, indice_treino+1:indice_validacao);
            saida_validacao = saida_real(indice_treino+1:indice_validacao);
            
            imagens_teste = imagens_entrada_dividido_aleatoriamente(:, :, :, indice_validacao+1:end);
            saida_teste = saida_real(indice_validacao+1:end);
        end
        
        function [entrada_da_rede, saida_cada_trecho] = ProcessamentoDadosRedeNeuralSimples(app)
            tamanho_sinal = length(app.sinal_eeg.sinal(1,:));
            tamanho_intervalo = 3;
            
            sinal_dividido = DividirSinalRNA(app.sinal_eeg.sinal(1,1:tamanho_sinal), tamanho_intervalo, app.sinal_eeg.frequencia_de_amostragem);
            trechos_sinal_associados = AssociarTrechosDeSinalComTipoDeEventoRNA(sinal_dividido, app.eventos_sinal);
        
            caracteristicas_do_sinal = ObterCaracteristicasDoSinal(trechos_sinal_associados);
            entrada_da_rede = cell2mat(caracteristicas_do_sinal);
                
            saida_cada_trecho = [ trechos_sinal_associados{:} ];
            saida_cada_trecho = [ saida_cada_trecho(:).ocorre_convulsao ];
        end
    
        function TreinarRedeNeuralSimples(app)
            [entrada_da_rede, saida_da_rede] = app.ProcessamentoDadosRedeNeuralSimples();
            app.rede_neural_simples = train(app.rede_neural_simples, entrada_da_rede, saida_da_rede);
        end
        
        function [saida_real, saida_resultante_da_rede] = ClassificarRedeNeuralSimples(app)
             if ~isempty(app.rede_neural_simples)
                 [entrada_da_rede, saida_real] = app.ProcessamentoDadosRedeNeuralSimples();
                 saida_resultante_da_rede = app.rede_neural_simples(entrada_da_rede);
                 
                 app.CalcularEstatisticasClassificacao(saida_real, saida_resultante_da_rede);
             end
        end
    
        function matriz = DicionarioParaMatriz(app, dicionario)
            celula = values(dicionario);
            celula = transpose(celula);
            matriz = cell2mat(celula);
        end
    
        function CalcularEstatisticasClassificacao(app, saida_real, saida_predita_da_rede)
            [c,cm,ind,per] = confusion(saida_real, saida_predita_da_rede);
            falso_negativo = per(2, 1);
            falso_positivo = per(2, 2);
            verdadeiro_positivo = per(2, 3);
            verdadeiro_negativo = per(2, 4);
            
            acuracia = (verdadeiro_positivo + verdadeiro_negativo) / ...
                (verdadeiro_positivo + verdadeiro_negativo + falso_negativo + falso_positivo);
            
            recall = verdadeiro_positivo / verdadeiro_positivo + falso_negativo;
            precision = verdadeiro_positivo / verdadeiro_positivo + falso_positivo;
            f_measure = (2 * recall * precision) / recall + precision;
            
            g_mean = sqrt(verdadeiro_positivo * verdadeiro_negativo);
            
            app.AlterarEstatisticas(verdadeiro_positivo, verdadeiro_negativo, falso_positivo, falso_negativo, acuracia, f_measure, g_mean);
        end
    
        function AlterarEstatisticas(app, verdadeiro_positivo, verdadeiro_negativo, falso_positivo, falso_negativo, acuracia, f_measure, g_mean)
            app.VerdadeirosPositivos.Value = strcat(string(round(verdadeiro_positivo * 100, 2)), '%');
            app.VerdadeirosNegativos.Value = strcat(string(round(verdadeiro_negativo * 100, 2)), '%');
            app.FalsoPositivos.Value = strcat(string(round(falso_positivo * 100, 2)), '%');
            app.FalsoNegativos.Value = strcat(string(round(falso_negativo * 100, 2)), '%');
            app.AcurciaTotal.Value = strcat(string(round(acuracia * 100, 2)), '%');
            
            app.Fmeasure.Value = string(round(f_measure, 2));
            app.Gmean.Value = string(round(g_mean, 2));
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('Matlab/'));
            CarregarRedeConvolucional(app);
            CarregarRedeNeural(app);
            app.TreinandoLabel.Visible = 'off';
            app.classificador_selecionado = app.SelecionarclassificadorDropDown.Value;
        end

        % Button pushed function: BuscarArquivoEDF
        function BuscarArquivoEDFButtonPushed(app, event)
            try
                [app.header_sinal_edf, app.sinal_carregado_edf, app.caminho_arquivos, app.nome_arquivo_edf] = ImportarArquivoEDF();
                app.caminho_arquivo_edf = strcat(app.caminho_arquivos, app.nome_arquivo_edf);
    
                app.nome_arquivo_tse = strrep(app.nome_arquivo_edf, '.edf', '.tse');
                app.caminho_arquivo_tse = strcat(app.caminho_arquivos, app.nome_arquivo_tse);
    
                app.eventos_sinal = ImportarEventos(app.caminho_arquivo_tse);
                app.sinal_eeg = SinalEEG(app.header_sinal_edf, app.sinal_carregado_edf, app.eventos_sinal);
                
                EDFCarregadoCorretamente(app);
            catch
                app.ClassificarDados.Enable = 'off';
                app.TreinarRede.Enable = 'off';
            end
        end

        % Callback function
        function ArquivoEDFEditFieldValueChanged(app, event)
            
        end

        % Value changed function: SelecionarclassificadorDropDown
        function SelecionarclassificadorDropDownValueChanged(app, event)
            app.classificador_selecionado = app.SelecionarclassificadorDropDown.Value;
        end

        % Button pushed function: TreinarRede
        function TreinarRedeButtonPushed(app, event)
            app.TreinandoLabel.Visible = 'on';
            switch app.classificador_selecionado
                case 'Deep Learning'
                    app.TreinarRedeConvolucional();
                case 'Rede Neural Simples'
                    app.TreinarRedeNeuralSimples();
            end
            app.ddmmyyyyhhmmssLabel.Text = string(datetime());
            app.ddmmyyyyhhmmssLabel.Visible = 'on';
            app.rede_foi_treinada = true;
            app.TreinandoLabel.Visible = 'off';
        end

        % Button pushed function: ResetarEstado
        function ResetarEstadoButtonPushed(app, event)
            app.startupFcn();
            app.AlterarEstatisticas(0, 0, 0, 0, 0, 0, 0);
            app.rede_foi_treinada = false;
        end

        % Button pushed function: ClassificarDados
        function ClassificarDadosButtonPushed(app, event)
            if app.rede_foi_treinada
                switch app.classificador_selecionado
                    case 'Deep Learning'
                        [saida_real, saida_estimada] = app.ClassificarRedeConvolucional();
                    case 'Rede Neural Simples'
                        [saida_real, saida_estimada] = app.ClassificarRedeNeuralSimples();
                end
                app.CalcularEstatisticasClassificacao(saida_real, saida_estimada);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArquivoSelecionadoTab = uitab(app.TabGroup);
            app.ArquivoSelecionadoTab.Title = 'Arquivo Selecionado';
            app.BuscarArquivoEDF = uibutton(app.ArquivoSelecionadoTab, 'push');
            app.BuscarArquivoEDF.ButtonPushedFcn = createCallbackFcn(app, @BuscarArquivoEDFButtonPushed, true);
            app.BuscarArquivoEDF.Text = 'Buscar Arquivo';
            app.ArquivoEDFEditFieldLabel = uilabel(app.ArquivoSelecionadoTab);
            app.ArquivoEDFEditFieldLabel.Text = 'Arquivo .EDF';
            app.ArquivoEDFEditField = uieditfield(app.ArquivoSelecionadoTab, 'text');
            app.ArquivoEDFEditField.Editable = 'off';
            app.ArquivoEDFEditField.Enable = 'off';
            app.ArquivoTSEEditFieldLabel = uilabel(app.ArquivoSelecionadoTab);
            app.ArquivoTSEEditFieldLabel.Text = 'Arquivo .TSE';
            app.ArquivoTSEEditField = uieditfield(app.ArquivoSelecionadoTab, 'text');
            app.ArquivoTSEEditField.Editable = 'off';
            app.ArquivoTSEEditField.Enable = 'off';
            app.ConfiguraoclassificadorTab = uitab(app.TabGroup);
            app.ConfiguraoclassificadorTab.Title = 'Configuração classificador';
            app.SelecionarclassificadorDropDownLabel = uilabel(app.ConfiguraoclassificadorTab);
            app.SelecionarclassificadorDropDownLabel.Text = 'Selecionar classificador';
            app.SelecionarclassificadorDropDown = uidropdown(app.ConfiguraoclassificadorTab);
            app.SelecionarclassificadorDropDown.Items = {'Rede Neural Simples', 'Deep Learning'};
            app.SelecionarclassificadorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecionarclassificadorDropDownValueChanged, true);
            app.SelecionarclassificadorDropDown.Value = 'Rede Neural Simples';
            app.TreinarRede = uibutton(app.ConfiguraoclassificadorTab, 'push');
            app.TreinarRede.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRede.Enable = 'off';
            app.TreinarRede.Text = {'Treinar rede com '; 'arquivo selecionado'};
            app.ClassificarDados = uibutton(app.ConfiguraoclassificadorTab, 'push');
            app.ClassificarDados.ButtonPushedFcn = createCallbackFcn(app, @ClassificarDadosButtonPushed, true);
            app.ClassificarDados.Enable = 'off';
            app.ClassificarDados.Text = {'Classificar dados '; 'de Arquivo selecionado'};
            app.ResetarEstado = uibutton(app.ConfiguraoclassificadorTab, 'push');
            app.ResetarEstado.ButtonPushedFcn = createCallbackFcn(app, @ResetarEstadoButtonPushed, true);
            app.ResetarEstado.Text = 'Resetar estado dos classifcadores';
            app.TreinandoLabel = uilabel(app.ConfiguraoclassificadorTab);
            app.TreinandoLabel.Text = 'Treinando ...';
            app.UltimotreinamentofeitoLabel = uilabel(app.ConfiguraoclassificadorTab);
            app.UltimotreinamentofeitoLabel.Text = 'Ultimo treinamento feito:';
            app.ddmmyyyyhhmmssLabel = uilabel(app.ConfiguraoclassificadorTab);
            app.ddmmyyyyhhmmssLabel.Visible = 'off';
            app.ddmmyyyyhhmmssLabel.Text = 'dd/mm/yyyy hh:mm:ss';
            app.DetectorconvulsoLabel = uilabel(app.UIFigure);
            app.DetectorconvulsoLabel.Text = 'Detector convulsão';
            app.EstatsticasClassificaoPanel = uipanel(app.UIFigure);
            app.EstatsticasClassificaoPanel.Title = 'Estatísticas Classificação';
            app.VerdadeirosPositivosEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.VerdadeirosPositivosEditFieldLabel.Text = 'Verdadeiros Positivos';
            app.VerdadeirosPositivos = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.VerdadeirosPositivos.Editable = 'off';
            app.VerdadeirosPositivos.Value = '0.0%';
            app.VerdadeirosNegativosEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.VerdadeirosNegativosEditFieldLabel.Text = 'Verdadeiros Negativos';
            app.VerdadeirosNegativos = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.VerdadeirosNegativos.Editable = 'off';
            app.VerdadeirosNegativos.Value = '0.0%';
            app.FalsoPositivosEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.FalsoPositivosEditFieldLabel.Text = 'Falso Positivos';
            app.FalsoPositivos = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.FalsoPositivos.Editable = 'off';
            app.FalsoPositivos.Value = '0.0%';
            app.FalsoNegativosEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.FalsoNegativosEditFieldLabel.Text = 'Falso Negativos';
            app.FalsoNegativos = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.FalsoNegativos.Editable = 'off';
            app.FalsoNegativos.Value = '0.0%';
            app.FmeasureEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.FmeasureEditFieldLabel.Text = 'F-measure';
            app.Fmeasure = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.Fmeasure.Editable = 'off';
            app.Fmeasure.Value = '0.0';
            app.GmeanEditFieldLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.GmeanEditFieldLabel.Text = 'G-mean';
            app.Gmean = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.Gmean.Editable = 'off';
            app.Gmean.Value = '0';
            app.AcuraciaTotalLabel = uilabel(app.EstatsticasClassificaoPanel);
            app.AcuraciaTotalLabel.Text = 'Acuracia Total';
            app.AcurciaTotal = uieditfield(app.EstatsticasClassificaoPanel, 'text');
            app.AcurciaTotal.Editable = 'off';
            app.AcurciaTotal.Value = '0.0%';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_detector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end